classdef NSPstreamerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                 matlab.ui.Figure
        MeasurementInformationPanel  matlab.ui.container.Panel
        PIDEditFieldLabel            matlab.ui.control.Label
        PIDEditField                 matlab.ui.control.EditField
        SIDEditFieldLabel            matlab.ui.control.Label
        SIDEditField                 matlab.ui.control.EditField
        ControlsPanel                matlab.ui.container.Panel
        StreamDataSwitchLabel        matlab.ui.control.Label
        StreamDataSwitch             matlab.ui.control.Switch
        RecordStO2CheckBox           matlab.ui.control.CheckBox
        StatusPanel                  matlab.ui.container.Panel
        IncomingdataLampLabel        matlab.ui.control.Label
        IncomingdataLamp             matlab.ui.control.Lamp
        StO2recordingLampLabel       matlab.ui.control.Label
        StO2recordingLamp            matlab.ui.control.Lamp
        LogTextAreaLabel             matlab.ui.control.Label
        LogTextArea                  matlab.ui.control.TextArea
        StatusHTMLLabel              matlab.ui.control.Label
        StatusHTML                   matlab.ui.control.HTML
        BoluspassageGaugeLabel       matlab.ui.control.Label
        BoluspassageGauge            matlab.ui.control.LinearGauge
    end

    
    % TODO: 
    % - Convert while loops in capture_watchdog and captureMeas into
    %       timer-objects. -> NEW BRANCH?!
    % - Implement accelerometer streaming
    
    % NOTES:
    % LSL streams:
    % - Trigger and Acc streams are created once configuration is loaded.
    %   Data stream is not created until recording has been started.
    % - Acc stream is created only if use_accelerometers in config is true.
    %   No data is transmitted if no accelerometers are found (eg if
    %   detectors were not connected when Aurora started!)
    
    
    properties (Access = public)
        PATHS % structure of required paths
%         lsl_cfg % structure w/ data required for lsl
        cnsts % NIRS related constants
        lsl_inlets = struct('nirs',[], 'trigger',[], 'acc',[]);
        lsl_InlNames = struct('nirs','Aurora', 'trigger','NIRStarTriggers','acc','Aurora_accelerometer');
        
    end
    
    properties (Access = private)
        appPath % path to mlapp file
        lsl_lib % lsl library handle, setup at startup
    end
    
    properties (Dependent)
        PID % Patient ID
        SID % Session ID
        capture_active % true when Streaming switch in on
        calc_StO2 % true when StO2 checkbox is on
    end
    
    properties (Constant)
        bolusPreTrgNum = 48;
        bolusTrgNum = [49 50 51];
        bolusInitTime = 10; % seconds - Defined by Bolus Control GUI!
        bolusPreLength = 30; % seconds
        bolusChunkSec = 100; % seconds
        StO2rate = 1; % seconds
        fileChunkLength = 5*60; % seconds
        lslTimeoutDuration = 5; % seconds
        
        outfNameTmpl_raw = @(dateStr,nchn)sprintf('%s_%03d_raw.csv',...
                                                dateStr, nchn);
        outfNameTmpl_bol = @(dateStr,nchn)sprintf('%s_%03d_perfusion.csv',...
                                                dateStr, nchn);
        outfNameTmpl_oxy = @(dateStr,nchn)sprintf('%s_%03d_oxygenation.csv',...
                                                dateStr, nchn);
    end
    
    %% ========== METHODS =================================================
    
    methods % ----------- GETTER & SETTER -------------------
        function pid = get.PID(app)
            pid = app.PIDEditField.Value;
        end
        function sid = get.SID(app)
            sid = app.SIDEditField.Value;
        end
        
        function active = get.capture_active(app)
            active = strcmpi(app.StreamDataSwitch.Value,'on');
        end
        function set.capture_active(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            if val
                app.StreamDataSwitch.Value = 'On';
            else
                app.StreamDataSwitch.Value = 'Off';
            end
        end
        
        function active = get.calc_StO2(app)
            active = strcmpi(app.RecordStO2CheckBox.Value,'on');
        end
        function set.calc_StO2(app,val)
            validateattributes(val,{'logical','numeric'},{'scalar','nonnegative','integer'},1);
            if val
                app.RecordStO2CheckBox.Value = 'On';
            else
                app.RecordStO2CheckBox.Value = 'Off';
            end
        end
    end % -----------------------------------------------


    methods (Access = public)
        function sts = lsl_init_input(app,varargin)
            % TODO: can we check if a stream is still alive/available
            % before we reopen/overwrite it?
            % NO, but existing stream with same name is reuseable even if
            % aurora has been restarted! So no need to recreated it?!
            
            sts = 0;
            if nargin<2 % the object input for methods is counted as 1 input.
                strms = fieldnames(app.lsl_InlNames);
            else
                strms = varargin;
                validateFH = @(x)validatestring(x,fieldnames(app.lsl_InlNames),'lsl_init_input','strms');
                strms = cellfun(validateFH,strms,'UniformOutput',false);
            end
            for k = 1:numel(strms)
                name = app.lsl_InlNames.(strms{k});
                result = lsl_resolve_byprop(app.lsl_lib,'name', name, 1, 1);
                if ~isempty(result)
                    app.lsl_inlets.(strms{k}) = lsl_inlet(result{1});
                else
                    sts = 1;
                    return;
                end
            end
        end % -------------------------------------------------------------
        
        
        function capture_watchdog(app)
            %
            waiting_for_inlet = false;
            waiting_for_data = false;
            while app.capture_active
                drawnow;
                
                % get lsl inlets ..........................................
                sts = app.lsl_init_input('nirs','trigger');
                if sts > 0
                    if ~waiting_for_inlet
                        app.setStatus(0,'Waiting for Data & Trigger lsl inlet...');
                        waiting_for_inlet = true;
                    end
                    
                    pause(1);
                    continue;
                end
                waiting_for_inlet = false;
                
                % probe inlets for incoming data ..........................
                nProbe = 2;
                s_ = cell(nProbe,1); t_ = cell(nProbe,1);
                for k = 1:nProbe
                    [s_{k}, t_{k}] = app.lsl_inlets.nirs.pull_sample(2);
                end
                strmData = [s_{:}];
                t = [t_{:}];
                if numel(t)<2
                    if ~waiting_for_data
                        app.setStatus(0,'lsl inlets found, no data received.');
                        waiting_for_data = true;
                    end
                    pause(1);
                    continue;                    
                end
                waiting_for_data = false;
                
                nchnLSL = size(strmData,1)-1;
                % try to infere start-time of measurement from frame count,
                % sampling rate ...........................................
                try 
                    dt = app.lsl_inlets.nirs.info.nominal_srate;
                    assert(~isempty(dt) && isnumeric(dt) && isscalar(dt) && dt>0, ...
                        'NSPstreamerGUI:capture_watchdog:lslSampleRateInvalid',...
                        'Sample rate from lsl stream meta data is invalid.');
                    app.setStatus(1,'Incoming lsl stream: fs = %.6f, nChn = %d', dt, nchnLSL);
                catch ME
                    dt = median(diff(t));
                    msg = sprintf('Failed to get sampling frequency from lsl stream meta data. Reason:\n\t%s\n Estimating from timestamps: %.6f',...
                        ME.message, dt);
                    app.setStatus(2,msg,'failedToRetrieveFS');
                end
                assert(~isempty(dt) && isnumeric(dt) && isscalar(dt) && dt>0, ...
                    'NSPstreamerGUI:capture_watchdog:failedToGetSampleRate',...
                    'Failed to get NIRS sample rate.');
                
                % find corresponding config.json...........................
                frmCnt = strmData(1,1);
                measRunTime = frmCnt*dt;
                startTime = now() - measRunTime/86400;
                % try to find matching measurement/config.json on disk. 
                dataDir = fullfile(app.PATHS.nspDataPth,datestr(now(),'yyyy-mm-dd'));
                if ~exist(dataDir,'dir')
                    dataDir = fullfile(app.PATHS.nspDataPth,datestr(now()-1,'yyyy-mm-dd'));
                end
                measDate = regexp(dataDir,'\d\d\d\d-\d\d-\d\d','match','once');
                measDirs = app.dir_filt(dataDir, sprintf('^%s_\\d\\d\\d$',measDate), 'dir');
                if isempty(measDirs)
                    app.setStatus(2,'lsl data received, but data not found on disk.</br>If Aurora is not in preview mode, check app paths!');
                    pause(1);
                    continue;                    
                end
                measDirNamSort = sort({measDirs.name});
                recentMeasID = measDirNamSort{end};
                cfgFile = app.dir_filt(fullfile(dataDir,recentMeasID), sprintf('^%s_config\\.json$',recentMeasID), 'file');
                if isempty(cfgFile)
                    app.setStatus(3,'lsl data received, but config.json not found on disk.</br>Data directory structure might be corrupted!');
                    pause(1);
                    continue;                    
                end
                if ~app.capture_active, break; end
                
                % compare estimated start time of measurement and
                % config.json file date
    % TODO: check for files created LATER than start time,
    % in case of preview measurement... can we check for preview in lsl
    % meta data? No such info in stream info xml...
    % https://biosignalsplux.com/downloads/apis/OpenSignals_Lab_Streaming_Layer_Guide_(MATLAB).pdf 
    % CAVE: SOMETIMES FILES ARE WRITTEN DESPITE OF PREVIEW MODE !?! -> BUG
    % REPORT?! Files are removed when preview is stopped?!
                dt = cfgFile.datenum - startTime; % >0 if file created after meas. start time
                % COMPLAIN if file was created more than 5 seconds BEFORE
                % measurement start:
                if dt < -5/86400 % abs(dt) > 30/86400
                    msg = {'The creation date of the most recent config.json file found does not match the estimated starting time of the received lsl stream!' ...
                         sprintf('name: %s, created: %s',cfgFile.name,datestr(cfgFile.datenum,'dd.mm.yy HH:MM:SS')) ...
                         'Do you want to use it anyway?'};
                    figure(app.MainUIFigure); % bring figure up
                    app.logger(msg);
                    r = uiconfirm(app.MainUIFigure, ...
                                  msg, ...
                                  'config.json time stamp not matching', ...
                                  'Icon','warning', 'Options',{'yes','retry','cancel'}, ...
                                  'DefaultOption',2, 'CancelOption',3);
                    app.logger(['->' r]);
                    switch r
                        case 'yes'
                        case 'retry'
                            continue;
                        case 'cancel'
                            app.capture_active = false;
                            break;
                        otherwise
                            app.capture_active = false;
                            error('Unhandled case.');
                    end
                end
                
                % check if number of channels in inlet and config match.
                cfgFileUri = fullfile(dataDir,recentMeasID,cfgFile.name);
                [nchnCfg, tag] = getCfgParam(cfgFileUri,'nchn','tag');
                if nchnCfg*4 ~= nchnLSL
                    figure(app.MainUIFigure); % bring figure up
                    msg = {'The number of channels in the most recent config.json file found does not match the number of channels in the received lsl stream!' ...
                         sprintf('%s: %d, lsl stream: %d', cfgFile.name, nchnCfg, nchnLSL/4) ...
                         };
                    uialert(app.MainUIFigure, msg);
                    app.logger(msg);
                    continue;
                end
                
                % use config.json to setup StO2 config
                try
                    stO2_cfg = stO2_getCFG(cfgFileUri); % define Sampling rate, Monitoring rate, NSP2 Channels assignment, Source/detector separations on probes, stream name, etc.
                    stO2_cfg.StO2rate = app.StO2rate; % Monitoring every second;

                catch ME
                    if ~app.calc_StO2
                       % ignore errors if stO2 disabled
                    elseif startsWith(ME.identifier,'getStO2')
                        msg = {'Failed to get StO2 patch configuration!' ...
                             sprintf('config: %s', cfgFile.name) ...
                             sprintf('tag: %s', tag) ...
                             'StO2 recording will be disabled. Continue?'};
                        app.logger(msg);
                        r = uiconfirm(app.MainUIFigure, ...
                                      msg, ...
                                      'StO2 configuration error', ...
                                      'Icon','error', 'Options',{'yes','no'}, ...
                                      'DefaultOption',2, 'CancelOption',2);
                        app.logger(['->' r]);
                        switch r
                            case 'yes'
                            case 'no'
                                app.capture_active = false;
                                break;
                            otherwise
                                app.capture_active = false;
                                error('Unhandled case.');
                        end
                    else % throw unhandled errors
                        rethrow(ME);
                    end
                    app.calc_StO2 = false;
                    app.RecordStO2CheckBox.Enable = 'off';
                    stO2_cfg = [];
                end
                
                % start measurement capture (separate method, handling file
                % splitting, copying etc)
                try
                    app.IncomingdataLamp.Enable = 'on';
                    if ~isempty(stO2_cfg), app.StO2recordingLamp.Enable = 'on'; end
                    app.captureMeasurement(cfgFileUri,stO2_cfg);
                catch ME
                    app.RecordStO2CheckBox.Enable = 'on';
                    app.IncomingdataLamp.Enable = 'off';
                    app.StO2recordingLamp.Enable = 'off';
                    rethrow(ME);
                end
                app.RecordStO2CheckBox.Enable = 'on';
                app.IncomingdataLamp.Enable = 'off';
                app.StO2recordingLamp.Enable = 'off';
            end
            
        end % -------------------------------------------------------------
        
        
        function captureMeasurement(app,cfgFileUri,stO2_cfg)
            %
            recStO2 = ~isempty(stO2_cfg);
            
            % set local and remote path ...................................
            outPth_local = fullfile(app.PATHS.outPath_fallback, app.PID, app.SID);
            outPth_remot = fullfile(app.PATHS.outPath,          app.PID, app.SID);
            
            
            % create local path ...........................................
            if ~exist(outPth_local,'dir')
                [sts,errmsg] = mkdir(outPth_local);
                if sts == 0
                    figure(app.MainUIFigure); % bring figure up
                    msg = {'Failed to create local path!' ...
                           outPth_local ...
                           sprintf('Reason: %s',errmsg) ...
                           'Cannot save data. Please check PID, SID and filesystem permissions!'...
                           };
                    uialert(app.MainUIFigure, msg);
                    app.setStatus(3,msg{1},'failedToCreateLocalPath');
                    app.logger(msg);
                    app.capture_active = false;
                    return;
                end
            end
            
            % create and probe remote path ................................
            if ~exist(outPth_remot,'dir')
                [sts,errmsg] = mkdir(outPth_remot);
            else
                testfile = fullfile(outPth_remot,'tmp.file');
                [fid,errmsg] = fopen(testfile,'w');
                try
                   fclose(fid);
                   delete(testfile);
                end
                sts = fid~=-1;
            end
            if sts == 0 % remote not accessible, warn!
                figure(app.MainUIFigure); % bring figure up
                msg = {'Failed to access remote path!' ...
                       outPth_remot ...
                       sprintf('Reason: %s',errmsg) ...
                       'Sending files to remote will probably fail. Continue Anyway?'...
                       };
                app.setStatus(3,msg{1},'failedToAccessRemotePath');
                app.logger(msg);
                r = uiconfirm(app.MainUIFigure, ...
                                  msg, ...
                                  'Remote path not available', ...
                                  'Icon','error', 'Options',{'yes','no'}, ...
                                  'DefaultOption',2, 'CancelOption',2);
                app.logger(['->' r]);
                switch r
                    case 'yes'
                    case 'no'
                        app.capture_active = false;
                        return;
                    otherwise
                        app.capture_active = false;
                        error('Unhandled case.');
                end
            end
            
            
            % get config info .............................................
            [nchn, fs, masterCfg, optim, chnMask, nAcc] = getCfgParam(cfgFileUri, ...
                'nchn','fs','mastercfg','optimized','channel_mask','nAccelerometer');
            [~,mstrCfgNam,~] = fileparts(masterCfg);
            
            
            % get Acc stream
            nAcc = 0; % TODO: downsample and sync 100 Hz Acc data (see below)
            if nAcc>0
                sts = app.lsl_init_input('acc');
                if sts > 0
                    msg = {'Configuration contains accelerometer but respective lsl streams could not be resolved.' ...
                           ' !! Accelerometer streaming DISABLED !!'};
                    app.logger(msg);
                    nAcc = 0;
                end
            end
            
            
            % provide layout files ........................................
            layoutBaseNam = regexprep(mstrCfgNam,'(_long)?(_sine|_rect)?$','');
            loFiles = dir(app.PATHS.optLayoutPath);
            loFileNames = {loFiles.name};
            lofPattern = sprintf('^(chn|opt)Pos_%s.csv$',layoutBaseNam);
            ilof = find(~cellfun(@isempty,regexp(loFileNames,lofPattern,'once')));
            if numel(ilof)~=2
                msg = sprintf('Unexpected number of optodeLayout-files found for setupID ''%s'' (%d), need 2!', ...
                    layoutBaseNam, numel(ilof));
                msg = {msg 'Continue anyway?'};
                app.logger(msg);
                r = uiconfirm(app.MainUIFigure, ...
                              msg, ...
                              'Unexpected number of optodeLayout-files', ...
                              'Icon','warning', 'Options',{'yes','no'}, ...
                              'DefaultOption',2, 'CancelOption',2);
                app.logger(['->' r]);
                switch r
                    case 'yes'
                    case 'no'
                        app.capture_active = false;
                        return;
                    otherwise
                        app.capture_active = false;
                        error('Unhandled case.');
                end
            end
            for i = 1:numel(ilof)
                for dest = {outPth_local, outPth_remot}
                    [s,msg] = copyfile(fullfile(app.PATHS.optLayoutPath, loFileNames{ilof(i)}), ...
                             dest{1});
                    if s==0
                        wrnMsg = sprintf('Failed to copy optode layout file:\n ''%s'' -> ''%s''\nReason: %s',...
                            loFileNames{ilof(i)}, dest{1}, msg);
                        app.setStatus(2,wrnMsg,'failedToCopyLayouts');
                    end
                end
            end
            
            
            % prepare file headers. .......................................
            header_rawHb = mkHeader(chnMask,'rawHb',nAcc);
            if recStO2
                header_stO2 = mkHeader(stO2_cfg.patchOptodes,'StO2',nAcc);
            end
            
            % set t0 ......................................................
%             cfgIfo = dir(cfgFileUri);
            bootDatetime = now-lsl_local_clock(app.lsl_lib)/86400;
            tOffset_uptime = (fix(now)-bootDatetime)*86400;
            tOffset_1970 = fix(now-datenum('1970-01-01 00:00','yyyy-mm-dd HH:MM'))*86400;
%             t0_uptime = now*86400-lsl_local_clock(app.lsl_lib); % rem(cfgIfo.datenum,1)*24*60*60;
            
            
            % initialize variable for capture loop ........................
            nirsFile = regexprep(cfgFileUri,'\.[^\.]+$','.nirs');
            measStopped = false;
            lslTimeout = false;
            ticLSL = tic;
            createNewFile = true;
            bolus_running = false;
            preBolusBuffLen = ceil(app.bolusPreLength*fs)*2;
            preBolusBuff = nan(nchn*4+3,preBolusBuffLen);
            if recStO2
                stO2Buff = [];
                Width_Wd = ceil(fs/stO2_cfg.StO2rate);
            end
            
            
            % . . . . . . . . . . . . . . . . . . . . . . . . . . . . . . .
            % stream data until button disabled or measurement stops (check
            % if .nirs file exists in data path and use timeout on incoming
            % lsl data in case aurora crashes w/o creating .nirs file.)
            while app.capture_active && ~measStopped && ~lslTimeout
                
                % split files after app.fileChunkLength seconds, do not split bolus. Copy
                % files after split to remote. Use non-blocking system-script.
                lslTimeout = toc(ticLSL) >= app.lslTimeoutDuration;
                [currentChunk, currentTStamp] = app.lsl_inlets.nirs.pullchunk();
                if isempty(currentChunk), continue; end
                ndp = size(currentChunk,2);
                
                
                % pull and process trigger.................................
                [trgChunk,trgTStamp] = app.lsl_inlets.trigger.pull_chunk();
                trgs = zeros(1,ndp);
                for ti = 1:numel(trgTStamp)
                    [~,mini] = min(abs(currentTStamp-trgTStamp(ti)));
                    trgs(mini) = trgChunk(2,ti);
                end
                
                
% T O D O >>>>> % pull and process accelerometer ..........................
                % see accelerometer notes in Documents\NIRx_NIRSport2
                if nAcc>0
                    [accChunk,accTStamp] = app.lsl_inlets.acc.pull_chunk();
                    accTStamp = accTStamp-tOffset_1970;
                    [accNum,iChnk,iUq] = unique(accChunk(:,2));
                    error('TODO');
                    
                end
                
                
                % create new files if neccessary ..........................
                if createNewFile
                    timeStmp = datestr(datetime - diff(seconds(currentTStamp([1 end]))),...
                                       'yyyymmdd-HHMMSS');
                    rawFile = fullfile(outPth_local,app.outfNameTmpl_raw(timeStmp,nchn));
                    app.write2file(rawFile,header_rawHb);
                    if recStO2
                        so2File = fullfile(outPth_local,app.outfNameTmpl_oxy(timeStmp,stO2_cfg.NPatch));
                        app.write2file(so2File,header_stO2);
                    end
                    
                    createNewFile = false;
                    samplCnt = 0;
                end
                
                
                % write data ..............................................
                currentDATA = [currentTStamp-tOffset_uptime; trgs; currentChunk];
                app.write2file(rawFile,currentDATA);
                samplCnt = samplCnt + ndp;
                
                
                % fill pre-Bolus-buff .....................................
                if ndp < preBolusBuffLen
                    preBolusBuff = [preBolusBuff(:,ndp+1:end) currentDATA];
                else
                    preBolusBuff = currentDATA;%(:,end-preBolusBuffLen+1:end);
                end
                
                
                % create files for bolus chunk ............................
                iBolus = ismember(currentDATA(2,:),app.bolusTrgNum(1));
                % TODO (minor): check for bolus abortion trigger?!
                if any(iBolus) && ~bolus_running
                    timeStmp = datestr(datetime - diff(seconds(currentDATA(2,[iBolus end]))),...
                                       'yyyymmdd-HHMMSS');
                    app.setStatus(1,'Bolus initiated (%s).', timeStmp);
                    bolus_running = true;
                    bolusTic = tic;
                    
                    % mk file, write header
                    bolFile = fullfile(outPth_local,app.outfNameTmpl_bol(timeStmp,nchn));
                    app.write2file(bolFile,header_rawHb);
                    
                    % get pre-bolus-chunk from bolus buffer
                    iBuff1 = size(preBolusBuff,2) - find(iBolus(end:-1:1),1,'first') + 1;
                    iBuff0 = max(1, iBuff1 - preBolusBuffLen/2 + 1);
                    preBolChnk = preBolusBuff(:,iBuff0:end);
                    
                    % remove NaNs
                    iBuff0 = find(~isnan(preBolChnk(1,:)),1,'first');
                    preBolChnk = preBolChnk(:,iBuff0:end);
                    preBolChnk(2,1) = app.bolusPreTrgNum;
                    
                    % write pre-bolus-chunk
                    app.write2file(bolFile,preBolChnk);
                    
                    % reset bolus gauge
                    app.BoluspassageGauge.Value = app.bolusInitTime;
                    
                elseif bolus_running % write data to bolus file ...........
                    app.write2file(bolFile,currentDATA);
                    app.BoluspassageGauge.Value = toc(bolusTic)-app.bolusInitTime;
                else
                end
                
                % truncate bolus buffer
                preBolusBuff = preBolusBuff(:,end-preBolusBuffLen+1:end);

                
                % calc StO2 ...............................................
                if recStO2
                    stO2Buff = [stO2Buff currentDATA];
                    for iBlk = 1:floor(size(stO2Buff,2)/Width_Wd)
                        bufIdx = (1:Width_Wd) + (iBlk-1)*Width_Wd;
                        bufOut = calc_OxStO2_new(sum(stO2Buff(3:end,bufIdx),2).', app.cnsts, stO2_cfg);
                        c = squeeze(bufOut.c).';
                        mua = bufOut.mua.';
                        writeOut = [stO2Buff(1:3,bufIdx(1)); ... frame; TStamp (first of StO2 chunk)
                                    squeeze(bufOut.St).'; ... St
                                    c(:); ... HbO; HbR
                                    mua(:); ... mua WL1; WL2
                                    ];
                        app.write2file(so2File,writeOut);
                    end
                    if ~isempty(iBlk) % remove processed data from buffer
                        stO2Buff = stO2Buff(:,(iBlk*Width_Wd)+1:end);
                    end
                end
                
                
                % stop bolus recording? ...................................
                if bolus_running  &&  toc(bolusTic) > app.bolusChunkSec+app.bolusInitTime
                    app.setStatus(1, 'Recording bolus chunk stopped. Copy file to remote...');
                    bolus_running = false;
                    app.copyFile(bolFile,outPth_remot);
                end

                
                % time for new files? .....................................
                if samplCnt/fs > app.fileChunkLength
                    createNewFile = true;
                    app.copyFile(rawFile,outPth_remot);
                    if recStO2
                        app.copyFile(so2File,outPth_remot);
                    end
                end             
                measStopped = exist(nirsFile,'file');
                ticLSL = tic;
                
            end % END while capture . . . . . . . . . . . . . . . . . . . .
            
            
            % measurement stopped, copy last files to remote...............
            if bolus_running, app.copyFile(bolFile,outPth_remot); end
            if ~createNewFile
                app.copyFile(rawFile,outPth_remot);
                if recStO2
                    app.copyFile(so2File,outPth_remot);
                end
            end
            
        end % -------------------------------------------------------------


        function write2file(app,file,data)
            % writes header if data ischar, otherwise assumes numeric data
            % and write with hard-coded format.
            % CONSIDER TO MAKE THIS STATIC?
            
            [fid,msg] = fopen(file,'a');
            if fid==-1
                error('NSPstreamerGUI:write2file:failedToOpenFile', ...
                    'Failed to open file ''%s'' for writing! Reason:\n %s',...
                    file, msg);
            end
            if ischar(data) % WRITE HEADER
                fprintf(fid,data);
            else % WRITE NUMERIC DATA
                ncol = size(data,1);
                frmt = ['\r\n%.3f,%d,%d' repmat(',%.8f',1,ncol-3)];
                fprintf(fid,frmt,data(:));
            end
            fclose(fid);
        end % -------------------------------------------------------------


        function copyFile(app,srcF,destP)
            %
            [srcP,srcN,srcE] = fileparts(srcF);
            srcN = [srcN srcE];
            
            logFile = sprintf('%s.log',tempname);
            
            try
                [sts,msg] = syscpFile(srcP,destP,srcN,logFile);
            catch ME
                sts = 1;
                msg = ME.message;
            end
            if sts ~= 0
                app.setStatus(2,sprintf('Failed to run script that copies file (''%s'') to remote:\n%s',srcN,msg),...
                    'failedToRunCopyScript');
                return;
            end
            
            app.createLogWatchTimer(srcN,logFile);
        end % -------------------------------------------------------------


        function createLogWatchTimer(app,srcN,logFile)
            %
            tName = sprintf('logWtchTimer_%s',srcN);
            tObj = timer(...
                    'ExecutionMode',    'fixedSpacing', ...
                    'Period',           5, ...
                    'StartDelay',       3, ...
                    'TasksToExecute',   20, ...
                    'Name',             tName, ...
                    'UserData',         {logFile,[],'',now}, ...
                    'StopFcn',          @(o,e)app.logWtchStopFnc(o,e), ...
                    'ErrorFcn',         @(o,e)app.logWtchErrFnc(o,e), ...
                    'TimerFcn',         @(o,e)app.logWtchTimerFnc(o,e) ...
                    );
            tObj.start();
        end % -------------------------------------------------------------


        function logWtchTimerFnc(app,tObj,evnt)
            %
            logFile = tObj.UserData{1};
            [sts,msg] = syscpGetStatus(logFile);
            tObj.UserData(2:3) = {sts,msg};
            switch sts
                case {-3 -2 0 3 4}
                    tObj.stop();
                case 2 % <<<<<< FOR TESTING, DISABLE IF STABLE: >>>>>>>>>>>
                    fprintf('%s: %s\n',tObj.Name, msg);
            end
        end % -------------------------------------------------------------


        function logWtchErrFnc(app,tObj,evnt)
            %
            fileNam = erase(tObj.Name,regexpPattern('^logWtchTimer_'));
            msg = sprintf('There was an error while probing the status of the copy process for file %s',fileNam);
            app.setStatus(2,msg,'logWatchTimerError');
            msg = sprintf('LogWatch timer function error message: %s',evnt.Data.message);
            app.logger(msg);
        end % -------------------------------------------------------------


        function logWtchStopFnc(app,tObj,~)
            %
            [logFile,sts,msg,tStart] = tObj.UserData{:};
            if exist(logFile,'file')
                try delete(logFile); end
            end
            switch sts
                case []
                    stsMsg = sprintf('LogWatch timer stopped without retrieved status for file ''%s''.',file);
                    app.setStatus(2,stsMsg,'failedToGetStatus');
                case num2cell(-3:1)
                    stsMsg = sprintf('Unexpected error while copying file ''%s'' to remote.',file);
                    app.setStatus(2,stsMsg,'copyScriptStoppedWithError');
                    logMsg = sprintf('LogWatch run for %.0f seconds and returned status: %s',(now-tStart)/86400,msg);
                    app.logger(logMsg);
                case 2
                    logMsg = sprintf('Copying of file ''%s'' seems to be still in progress after %.0f seconds: %s.\nStopping surveillance!',file,msg);
                    app.logger(logMsg);
                case 3
                    logMsg = sprintf('Successfully copied file ''%s'' to remote.',file);
                    app.logger(logMsg);
                case 4
                    stsMsg = sprintf('Failed to copy file ''%s'' to remote. File not found?!',file);
                    app.setStatus(2,stsMsg,'file2copyNotFound');
                case 99
                    stsMsg = sprintf('Failed to get status while copying file ''%s'' to remote.',file);
                    app.setStatus(2,stsMsg,'copyScriptUnknownStatus');
                    logMsg = sprintf('LogWatch run for %.0f seconds and stopped with unknown status.',(now-tStart)/86400);
                    app.logger(logMsg);
                otherwise
                    app.setStatus(3,'Unhandled case for copy status. This is a bug.','copyStatusUnhandledCase');
            end
            tObj.delete();
        end % -------------------------------------------------------------


        function logger(app,msg)
            %
            if iscellstr(msg) || isstring(msg), msg = strjoin(msg,'\n'); end
            warning('TODO: logger.\n%s',msg)
        end % -------------------------------------------------------------


        function setStatus(app,level,msg,id)
            % level: 0: transparent, 1: green, 2: yellow, 3: red
            warning('TODO: setStatus method.\n%s',msg)
            if exist('id','var')
                % TODO: issue warning, build id with dbStack and id
            end
            app.logger(msg);
        end % -------------------------------------------------------------
    end % methods public ==================================================


    methods (Static)
        function d = dir_filt(pth,filt,typ)
            % tpy: 'dir' 'file' or 'any' [default]
            if nargin < 3, typ = 'any'; end
            typ = validatestring(typ,{'dir','file','any'},3);
            d = dir(pth);
            if      strcmpi(typ,'dir'),  d = d( [d.isdir]);
            elseif  strcmpi(typ,'file'), d = d(~[d.isdir]);
            end
            idx = ~cellfun(@isempty,regexp({d.name},filt,'once'));
            d = d(idx);
        end
    end


    %% ===================== v CALLBACKS v ================================

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setStatus(0,'Initialize...');
            drawnow();
            app.appPath = fileparts(fileparts(mfilename("fullpath")));
            addpath(app.appPath);
            addpath(fullfile(app.appPath,'util'));
            app.PATHS = setPath();
            
            try
                app.lsl_lib = lsl_loadlib();
            catch ME
                app.setStatus(3,'FATAL: FAILED TO LOAD LSL-LIB!');
                app.StreamDataSwitch.Enable = 'off';
                rethrow(ME);
            end
            
            app.BoluspassageGauge.Limits = [-app.bolusInitTime app.bolusChunkSec];
            app.BoluspassageGauge.Value = [-app.bolusInitTime];
            x0 = fix(-app.bolusInitTime/10)*10;
            x1 = fix( app.bolusChunkSec/10)*10;
            app.BoluspassageGauge.MajorTicks = (x0:10:x1);
            app.BoluspassageGauge.MinorTicks = (x0:5:x1);
            app.BoluspassageGauge.Value = -app.bolusInitTime;

            app.SIDEditField.Value = datestr(fix(now*48)/48,'yyyymmdd-HHMMSS');
            
            app.cnsts = constants_init('AdultHead');
            app.setStatus(0,'Ready.');


        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            try rmpath(app.appPath); end
            try rmpath(fullfile(app.appPath,'util')); end

            delete(app)
            
        end

        % Value changed function: StreamDataSwitch
        function StreamDataSwitchValueChanged(app, event)
            if app.capture_active
                try
                    app.capture_watchdog();
                    app.RecordStO2CheckBox.Enable = 'on';
                catch ME
                    app.setStatus(3,sprintf('FATAL: UNHANDLED EXCEPTION WHILE EXECUTING CAPTURE_WATCHDOG! sorry.</br>in <i>%s</i>, line %d', ...
                                            ME.stack(1).name, ME.stack(1).line));
                    app.capture_active = false;
                    app.RecordStO2CheckBox.Enable = 'on';
                    rethrow(ME);
                end
            end
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'NSPstreamerMainUI';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.MeasurementInformationPanel = uipanel(app.MainUIFigure);
            app.MeasurementInformationPanel.Title = 'Measurement Information';
            app.PIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.PIDEditFieldLabel.Text = 'PID';
            app.PIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.PIDEditField.Tooltip = {'Enter Patient ID, PIZ# or Animal ID (e.g. ENABLE_003)'};
            app.SIDEditFieldLabel = uilabel(app.MeasurementInformationPanel);
            app.SIDEditFieldLabel.Text = 'SID';
            app.SIDEditField = uieditfield(app.MeasurementInformationPanel, 'text');
            app.SIDEditField.Tooltip = {'Enter Session ID: YYYYMMDD-HHMM00'};
            app.ControlsPanel = uipanel(app.MainUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StreamDataSwitchLabel = uilabel(app.ControlsPanel);
            app.StreamDataSwitchLabel.Text = 'Stream Data';
            app.StreamDataSwitch = uiswitch(app.ControlsPanel, 'slider');
            app.StreamDataSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamDataSwitchValueChanged, true);
            app.RecordStO2CheckBox = uicheckbox(app.ControlsPanel);
            app.RecordStO2CheckBox.Text = 'Record StO2';
            app.StatusPanel = uipanel(app.MainUIFigure);
            app.StatusPanel.Title = 'Status';
            app.IncomingdataLampLabel = uilabel(app.StatusPanel);
            app.IncomingdataLampLabel.Text = 'Incoming data';
            app.IncomingdataLamp = uilamp(app.StatusPanel);
            app.IncomingdataLamp.Enable = 'off';
            app.StO2recordingLampLabel = uilabel(app.StatusPanel);
            app.StO2recordingLampLabel.Text = 'StO2 recording';
            app.StO2recordingLamp = uilamp(app.StatusPanel);
            app.StO2recordingLamp.Enable = 'off';
            app.LogTextAreaLabel = uilabel(app.StatusPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.StatusPanel);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Value = {'asdf'; 'adsf'};
            app.StatusHTMLLabel = uilabel(app.StatusPanel);
            app.StatusHTMLLabel.Text = 'Status';
            app.StatusHTML = uihtml(app.StatusPanel);
            app.StatusHTML.HTMLSource = '<body><span style="background-color:red;align-items: center;display: flex;">a</span></body>';
            app.BoluspassageGaugeLabel = uilabel(app.StatusPanel);
            app.BoluspassageGaugeLabel.Enable = 'off';
            app.BoluspassageGaugeLabel.Text = 'Bolus passage';
            app.BoluspassageGauge = uigauge(app.StatusPanel, 'linear');
            app.BoluspassageGauge.Limits = [-10 100];
            app.BoluspassageGauge.MajorTicks = [-10 0 10 20 30 40 50 60 70 80 90 100];
            app.BoluspassageGauge.MinorTicks = [-10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.BoluspassageGauge.Enable = 'off';
            app.BoluspassageGauge.Value = -10;
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSPstreamerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end